classdef LiveDataPPTsAndFlowAndCamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LiveDataAcquisitionUIFigure     matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        FreqHzDropDown                  matlab.ui.control.DropDown
        FreqHzDropDownLabel             matlab.ui.control.Label
        CameraSwitch                    matlab.ui.control.Switch
        CameraSwitchLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LiveAxes_4                      matlab.ui.control.UIAxes
        DevicePanel_4                   matlab.ui.container.Panel
        RateSlider                      matlab.ui.control.Slider
        RatescanssSliderLabel           matlab.ui.control.Label
        RateEdit                        matlab.ui.control.NumericEditField
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DevicePanel_3                   matlab.ui.container.Panel
        PPT3Label                       matlab.ui.control.Label
        CouplingDropDown_3              matlab.ui.control.DropDown
        CouplingDropDown_3Label         matlab.ui.control.Label
        TerminalConfigDropDown_3        matlab.ui.control.DropDown
        TerminalConfigDropDown_3Label   matlab.ui.control.Label
        RangeDropDown_3                 matlab.ui.control.DropDown
        RangeDropDown_3Label            matlab.ui.control.Label
        MeasurementTypeDropDown_3       matlab.ui.control.DropDown
        MeasurementTypeDropDown_3Label  matlab.ui.control.Label
        ChannelDropDown_3               matlab.ui.control.DropDown
        ChannelDropDown_3Label          matlab.ui.control.Label
        DevicePanel_2                   matlab.ui.container.Panel
        MeasurementTypeDropDown_2       matlab.ui.control.DropDown
        MeasurementTypeDropDown_2Label  matlab.ui.control.Label
        PPT2Label                       matlab.ui.control.Label
        CouplingDropDown_2              matlab.ui.control.DropDown
        CouplingDropDown_2Label         matlab.ui.control.Label
        TerminalConfigDropDown_2        matlab.ui.control.DropDown
        TerminalConfigDropDown_2Label   matlab.ui.control.Label
        RangeDropDown_2                 matlab.ui.control.DropDown
        RangeDropDown_2Label            matlab.ui.control.Label
        ChannelDropDown_2               matlab.ui.control.DropDown
        ChannelDropDown_2Label          matlab.ui.control.Label
        LiveViewPanel                   matlab.ui.container.Panel
        TimewindowEditField             matlab.ui.control.NumericEditField
        TimewindowsEditFieldLabel       matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        AutoscaleYSwitch                matlab.ui.control.Switch
        AutoscaleYSwitchLabel           matlab.ui.control.Label
        LiveAxes_2                      matlab.ui.control.UIAxes
        LiveAxes_3                      matlab.ui.control.UIAxes
        LiveAxes                        matlab.ui.control.UIAxes
        LiveDataAcquisitionPermeameterLabel  matlab.ui.control.Label
        DevicePanel                     matlab.ui.container.Panel
        PPT1Label                       matlab.ui.control.Label
        CouplingDropDown                matlab.ui.control.DropDown
        CouplingDropDownLabel           matlab.ui.control.Label
        TerminalConfigDropDown          matlab.ui.control.DropDown
        TerminalConfigDropDownLabel     matlab.ui.control.Label
        RangeDropDown                   matlab.ui.control.DropDown
        RangeDropDownLabel              matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        AcquisitionPanel                matlab.ui.container.Panel
        LogStatusText                   matlab.ui.control.Label
        LogdatatofileSwitch             matlab.ui.control.Switch
        LogdatatofileSwitchLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
    end

   
    properties (Access = private)
        DAQ                   % Handle to DAQ object
        C                     % Camera object
        timerObj              % Timer for camera
        DAQMeasurementTypes = {'Voltage','IEPE','Audio'};  % DAQ input measurement types supported by the app
        DAQSubsystemTypes = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        DevicesInfo           % Array of devices that provide analog input voltage or audio input measurements
        LogRequested          % Logical value, indicates whether user selected to log data to file from the UI (set by LogdatatofileSwitch)
        CamLogRequested       % Logical value, indicates whether user selected to log camera data to file from the UI (set by CameraSwitch)
        CamFreq               % Frequency for camera
        TimestampsFIFOBuffer  % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer        % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        FIFOMaxSize = 1E+6    % Maximum allowed FIFO buffer size for DataFIFOBuffer and TimestampsFIFOBuffer
        LivePlotLine          % Handle to line plot of acquired data
        LivePlotLine2          % Handle to line plot of acquired data
        LivePlotLine3          % Handle to line plot of acquired data
        LivePlotLine4          % Handle to line plot of acquired data
        TriggerTime           % Acquisition start time stored as datetime
        TempFilename          % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile              % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = 'daqdata.mat' % Default MAT file name at app start
        Filepath = pwd        % Default folder for saving the MAT file at app start
    end
    
    methods (Access = private)
        
        
        function scansAvailable_Callback(app, src, ~)
        %scansAvailable_Callback Executes on DAQ object ScansAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.
            
            if ~isvalid(app)
                return
            end
            
            [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
            
            if app.LogRequested
                % If Log data to file switch is on
                latestdata = [timestamps, data]' ;
                fwrite(app.TempFile, latestdata, 'double');
                if timestamps(1)==0
                    app.TriggerTime = triggertime;
                end
            end
            
            % Store continuous acquisition data in FIFO data buffers
            buffersize = round(app.DAQ.Rate * app.TimewindowEditField.Value) + 1;
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, timestamps);
            % app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data(:,1));
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, data(:,1:4));


            % Update plot data
            % set(app.LivePlotLine, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer);
            set(app.LivePlotLine, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer(:,1));
            set(app.LivePlotLine2, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer(:,2));
            set(app.LivePlotLine3, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer(:,3));
            set(app.LivePlotLine4, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer(:,4));
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.LiveAxes, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
                xlim(app.LiveAxes_2, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
                xlim(app.LiveAxes_3, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
                xlim(app.LiveAxes_4, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
        end
        
        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (datablock) is added to the buffer and if the amount of data in the
        %  buffer exceeds the specified buffer size (buffersize) the oldest data is discarded to cap the size of
        %  the data in the buffer to buffersize.
        %  input data is the existing data buffer (column vector Nx1).
        %  buffersize is the desired buffer size (maximum number of rows in data buffer) and can be changed.
        %  datablock is a new data block to be added to the buffer (column vector Kx1).
        %  output data is the updated data buffer (column vector Mx1).
        
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
        end
        
        function [items, itemsData] = getChannelPropertyOptions(~, subsystem, propertyName)
        %getChannelPropertyOptions Get options available for a DAQ channel property
        %  Returns items and itemsData for displaying options in a dropdown component
        %  subsystem is the DAQ subsystem handle corresponding to the DAQ channel
        %  propertyName is channel property name as a character array, and can be
        %    'TerminalConfig', or 'Coupling', or 'Range'.
        %  items is a cell array of possible property values, for example {'DC', 'AC'}
        %  itemsData is [] (empty) for 'TerminalConfig' and 'Coupling', and is a cell array of
        %     available ranges for 'Range', for example {[-10 10], [-1 1]}
            
            switch propertyName
                case 'TerminalConfig'
                    items = cellstr(string(subsystem.TerminalConfigsAvailable));
                    itemsData = [];
                case 'Coupling'
                    items = cellstr(string(subsystem.CouplingsAvailable));
                    itemsData = [];
                case 'Range'
                    numRanges = numel(subsystem.RangesAvailable);
                    items = strings(numRanges,1);
                    itemsData = cell(numRanges,1);
                    for ii = 1:numRanges
                        range = subsystem.RangesAvailable(ii);
                        items(ii) = sprintf('%.2f to %.2f', range.Min, range.Max);
                        itemsData{ii} = [range.Min range.Max];
                    end
                    items = cellstr(items);                    
            end
        end
        
        
        function setAppViewState(app, state)
        %setAppViewState Sets the app in a new state and enables/disables corresponding components
        %  state can be 'deviceselection', 'configuration', 'acquisition', or 'filesave'
        
            switch state                
                case 'deviceselection'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'on';
                    app.ChannelDropDown.Enable = 'off';
                    app.ChannelDropDown_2.Enable = 'off';
                    app.ChannelDropDown_3.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.MeasurementTypeDropDown_3.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.RangeDropDown_3.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_3.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.CouplingDropDown_3.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'off';
                case 'configuration'
                    app.RateEdit.Enable = 'on';
                    app.RateSlider.Enable = 'on';
                    app.DeviceDropDown.Enable = 'on';
                    app.ChannelDropDown.Enable = 'on';
                    app.ChannelDropDown_2.Enable = 'on';
                    app.ChannelDropDown_3.Enable = 'on';
                    app.MeasurementTypeDropDown.Enable = 'on';
                    app.MeasurementTypeDropDown_2.Enable = 'on';
                    app.MeasurementTypeDropDown_3.Enable = 'on';
                    app.RangeDropDown.Enable = 'on';
                    app.RangeDropDown_2.Enable = 'on';
                    app.RangeDropDown_3.Enable = 'on';
                    app.StartButton.Enable = 'on';
                    app.LogdatatofileSwitch.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'on';

                    switch app.DAQ.Channels(1).MeasurementType
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown.Enable = 'off';
                            app.CouplingDropDown.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown.Enable = 'on';
                            app.CouplingDropDown.Enable = 'on';
                    end

                    switch app.DAQ.Channels(2).MeasurementType
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_2.Enable = 'on';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_2.Enable = 'off';
                            app.CouplingDropDown_2.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_2.Enable = 'on';
                    end

                    switch app.DAQ.Channels(3).MeasurementType
                        case 'Voltage'
                            % Voltage channels do not have ExcitationSource
                            % property, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_3.Enable = 'on';
                            app.CouplingDropDown_3.Enable = 'on';
                        case 'Audio'
                            % Audio channels do not have TerminalConfig, Coupling, and ExcitationSource
                            % properties, so disable the corresponding UI controls
                            app.TerminalConfigDropDown_2.Enable = 'off';
                            app.CouplingDropDown_3.Enable = 'off';
                        case 'IEPE'
                            app.TerminalConfigDropDown_2.Enable = 'on';
                            app.CouplingDropDown_3.Enable = 'on';
                    end

                case 'acquisition'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.ChannelDropDown_2.Enable = 'off';
                    app.ChannelDropDown_3.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.MeasurementTypeDropDown_3.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.RangeDropDown_3.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_3.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.CouplingDropDown_3.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.TimewindowEditField.Enable = 'on';
                    updateLogdatatofileSwitchComponents(app)
                case 'filesave'
                    app.RateEdit.Enable = 'off';
                    app.RateSlider.Enable = 'off';
                    app.DeviceDropDown.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.ChannelDropDown_2.Enable = 'off';
                    app.ChannelDropDown_3.Enable = 'off';
                    app.MeasurementTypeDropDown.Enable = 'off';
                    app.MeasurementTypeDropDown_2.Enable = 'off';
                    app.MeasurementTypeDropDown_3.Enable = 'off';
                    app.RangeDropDown.Enable = 'off';
                    app.RangeDropDown_2.Enable = 'off';
                    app.RangeDropDown_3.Enable = 'off';
                    app.TerminalConfigDropDown.Enable = 'off';
                    app.TerminalConfigDropDown_2.Enable = 'off';
                    app.TerminalConfigDropDown_3.Enable = 'off';
                    app.CouplingDropDown.Enable = 'off';
                    app.CouplingDropDown_2.Enable = 'off';
                    app.CouplingDropDown_3.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.LogdatatofileSwitch.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.TimewindowEditField.Enable = 'on';
                    updateLogdatatofileSwitchComponents(app)   
            end
        end
        
        
        function binFile2MAT(~, filenameIn, filenameOut, numColumns, metadata)
        %BINFILE2MAT Loads 2-D array of doubles from binary file and saves data to MAT file
        % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading
        % all data to memory.
        % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
        % Input binary file is a matrix of doubles with numRows x numColumns
        % MAT file (filenameOut) is a MAT file with the following variables
        %   timestamps = a column vector ,  the first column in the data from binary file
        %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
        %   metatada = a structure, which is provided as input argument, used to provide additional
        %              data information
        %
            
            % If filenameIn does not exist, error out
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end
            
            % If output MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end
            
            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.PPTdata(numRows,3) = 0;
            matObj.FLOWdata(numRows,1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to
                % process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double');
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.PPTdata((ii+1):(ii+chunkSize), 1) = data(2,:)';
                matObj.PPTdata((ii+1):(ii+chunkSize), 2) = data(3,:)';
                matObj.PPTdata((ii+1):(ii+chunkSize), 3) = data(4,:)';
                matObj.FLOWdata((ii+1):(ii+chunkSize), 1) = data(5,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);
            
            % Save provided metadata to MAT file
            matObj.metadata = metadata;
        end
        
        function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes)
        %daqListSupportedDevices Get connected devices that support the specified subsystem and measurement types      
            
            % Detect all connected devices
            devices = daqlist;
            deviceinfo = devices.DeviceInfo;
            
            % Keep a subset of devices which have the specified subystem and measurement types
            deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes);
            deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);
            
        end
                
        function filteredDevices = daqFilterDevicesBySubsystem(~, devices, subsystemTypes)
        %daqFilterDevicesBySubsystem Filter DAQ device array by subsystem type
        %  devices is a DAQ device info array
        %  subsystemTypes is a cell array of DAQ subsystem types, for example {'AnalogInput, 'AnalogOutput'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the subsystem types provided
            hasSubsystemArray = false(numel(devices), 1);
            
            % Go through each device and see if it has any of the subsystem types provided
            for ii = 1:numel(devices)
                hasSubsystem = false;
                for jj = 1:numel(subsystemTypes)
                    hasSubsystem = hasSubsystem || ...
                        any(strcmp({devices(ii).Subsystems.SubsystemType}, subsystemTypes{jj}));
                end
                hasSubsystemArray(ii) = hasSubsystem;
            end
            filteredDevices = devices(hasSubsystemArray);
        end
        
        function filteredDevices = daqFilterDevicesByMeasurement(~, devices, measurementTypes)
        %daqFilterDevicesByMeasurement Filter DAQ device array by measurement type
        %  devices is a DAQ device info array
        %  measurementTypes is a cell array of measurement types, for example {'Voltage, 'Current'}
        %  filteredDevices is the filtered DAQ device info array
            
            % Logical array indicating if device has any of the measurement types provided
            hasMeasurementArray = false(numel(devices), 1);
            
            % Go through each device and subsystem and see if it has any of the measurement types provided
            for ii = 1:numel(devices)
                % Get array of available subsystems for the current device
                subsystems = [devices(ii).Subsystems];
                hasMeasurement = false;
                for jj = 1:numel(subsystems)
                    % Get cell array of available measurement types for the current subsystem
                    measurements = subsystems(jj).MeasurementTypesAvailable;
                    for kk = 1:numel(measurementTypes)
                        hasMeasurement = hasMeasurement || ...
                            any(strcmp(measurements, measurementTypes{kk}));
                    end
                end
                hasMeasurementArray(ii) = hasMeasurement;
            end
            filteredDevices = devices(hasMeasurementArray);
        end
        
        function updateRateUIComponents(app)
        %updateRateUIComponents Updates UI with current rate and time window limits
            
            % Update UI to show the actual data acquisition rate and limits
            value = app.DAQ.Rate;
            app.RateEdit.Limits = app.DAQ.RateLimit;
            app.RateSlider.Limits = app.DAQ.RateLimit;
            app.RateSlider.MajorTicks = [app.DAQ.RateLimit(1) app.DAQ.RateLimit(2)];
            app.RateSlider.MinorTicks = [];
            app.RateEdit.Value = value;
            app.RateSlider.Value = value;
            
            % Update time window limits
            % Minimum time window shows 2 samples
            % Maximum time window corresponds to the maximum specified FIFO buffer size
            minTimeWindow = 1/value;
            maxTimeWindow = app.FIFOMaxSize / value;
            app.TimewindowEditField.Limits = [minTimeWindow, maxTimeWindow];
            
        end
        
        
        function closeApp_Callback(app, ~, event, isAcquiring)
        %closeApp_Callback Clean-up after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is called from CloseRequestFcn
        %  of the app UIFigure.
        %   event is the event data of the UIFigure CloseRequestFcn callback.
        %   isAcquiring is a logical flag (true/false) corresponding to DAQ
        %   running state.            
            
            %   Before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   data acquisition object and close file if logging.
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQ)
                        delete(app.DAQ)
                        if app.LogRequested
                            fclose(app.TempFile);
                        end
                    else
                        % Acquisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
            
        end
        
        function updateAutoscaleYSwitchComponents(app)
        %updateAutoscaleYSwitchComponents Updates UI components related to y-axis autoscale
        
            value = app.AutoscaleYSwitch.Value;
            switch value
                case 'Off'
                    app.YminEditField.Enable = 'on';
                    app.YmaxEditField.Enable = 'on';
                    YmaxminValueChanged(app, []);
                case 'On'
                    app.YminEditField.Enable = 'off';
                    app.YmaxEditField.Enable = 'off';
                    app.LiveAxes.YLimMode = 'auto';
                    app.LiveAxes_2.YLimMode = 'auto';
                    app.LiveAxes_3.YLimMode = 'auto';
                    app.LiveAxes_4.YLimMode = 'auto';
            end
        end
        
        function updateChannelMeasurementComponents(app)
        %updateChannelMeasurementComponents Updates channel properties and measurement UI components

            % Get selected DAQ device index (to be used with DaqDevicesInfo list)
            deviceIndex = app.DeviceDropDown.Value - 1;
            deviceID = app.DevicesInfo(deviceIndex).ID;
            vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                        
            % Get DAQ subsystem information (analog input or audio input)
            % Analog input or analog output subsystems are the first subsystem of the device
            subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
            
            % Delete existing data acquisition object
            delete(app.DAQ);
            app.DAQ = [];
            
            % Create a new data acquisition object
            d = daq(vendor);
            addinput(d, deviceID, app.ChannelDropDown.Value, app.MeasurementTypeDropDown.Value);
            addinput(d, deviceID, app.ChannelDropDown_2.Value, app.MeasurementTypeDropDown_2.Value);
            addinput(d, deviceID, app.ChannelDropDown_3.Value, app.MeasurementTypeDropDown_3.Value);
            addinput(d, deviceID, "ai7", "Voltage");
            
            % Configure DAQ ScansAvailableFcn callback function
            d.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
            
            % Store data acquisition object handle in DAQ app property
            app.DAQ = d;
             
            % Only 'Voltage', 'IEPE' and 'Audio' measurement types are supported in this version of the app
            % Depending on what type of device is selected, populate the UI elements channel properties
            switch subsystem.SubsystemType
                case 'AnalogInput'                                       
                    % Populate dropdown with available channel 'TerminalConfig' options
                    app.TerminalConfigDropDown.Items = getChannelPropertyOptions(app, subsystem, 'TerminalConfig');
                    app.TerminalConfigDropDown_2.Items = getChannelPropertyOptions(app, subsystem, 'TerminalConfig');
                    app.TerminalConfigDropDown_3.Items = getChannelPropertyOptions(app, subsystem, 'TerminalConfig');
                    % Update UI with the actual channel property value
                    % (default value is not necessarily first in the list)
                    % DropDown Value must be set as a character array in MATLAB R2017b
                    app.TerminalConfigDropDown.Value = d.Channels(1).TerminalConfig;
                    app.TerminalConfigDropDown_2.Value = d.Channels(2).TerminalConfig;
                    app.TerminalConfigDropDown_3.Value = d.Channels(3).TerminalConfig;
                    app.TerminalConfigDropDown.Tag = 'TerminalConfig';
                    app.TerminalConfigDropDown_2.Tag = 'TerminalConfig';
                    app.TerminalConfigDropDown_3.Tag = 'TerminalConfig';
                    
                    % Populate dropdown with available channel 'Coupling' options
                    app.CouplingDropDown.Items =  getChannelPropertyOptions(app, subsystem, 'Coupling');
                    app.CouplingDropDown_2.Items =  getChannelPropertyOptions(app, subsystem, 'Coupling');
                    app.CouplingDropDown_3.Items =  getChannelPropertyOptions(app, subsystem, 'Coupling');
                    % Update UI with the actual channel property value
                    app.CouplingDropDown.Value = d.Channels(1).Coupling;
                    app.CouplingDropDown_2.Value = d.Channels(2).Coupling;
                    app.CouplingDropDown_3.Value = d.Channels(3).Coupling;
                    app.CouplingDropDown.Tag = 'Coupling';
                    app.CouplingDropDown_2.Tag = 'Coupling';
                    app.CouplingDropDown_3.Tag = 'Coupling';
                    
                    ylabel(app.LiveAxes, 'Voltage (V)')
                    ylabel(app.LiveAxes_2, 'Voltage (V)')
                    ylabel(app.LiveAxes_3, 'Voltage (V)')
                    ylabel(app.LiveAxes_4, 'Counts (-)')
                                        
                case 'AudioInput'
                    ylabel(app.LiveAxes, 'Normalized amplitude')
                    ylabel(app.LiveAxes_2, 'Normalized amplitude')
                    ylabel(app.LiveAxes_3, 'Normalized amplitude')
            end
            
            % Update UI with current rate and time window limits
            updateRateUIComponents(app)
                    
            % Populate dropdown with available 'Range' options
            [rangeItems, rangeItemsData] = getChannelPropertyOptions(app, subsystem, 'Range');
            app.RangeDropDown.Items = rangeItems;
            app.RangeDropDown_2.Items = rangeItems;
            app.RangeDropDown_3.Items = rangeItems;
            app.RangeDropDown.ItemsData = rangeItemsData;
            app.RangeDropDown_2.ItemsData = rangeItemsData;
            app.RangeDropDown_3.ItemsData = rangeItemsData;
            
            % Update UI with current channel 'Range'
            currentRange1 = d.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange1.Min currentRange1.Max];
            app.RangeDropDown.Tag = 'Range';
            currentRange2 = d.Channels(2).Range;
            app.RangeDropDown_2.Value = [currentRange2.Min currentRange2.Max];
            app.RangeDropDown_2.Tag = 'Range';
            currentRange3 = d.Channels(3).Range;
            app.RangeDropDown_3.Value = [currentRange3.Min currentRange3.Max];
            app.RangeDropDown_3.Tag = 'Range';
            
            app.DeviceDropDown.Items{1} = 'Deselect device';
            
            % Enable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'configuration');
        end
        
        function updateLogdatatofileSwitchComponents(app)
            value = app.LogdatatofileSwitch.Value;
            switch value
                case 'Off'
                    app.LogRequested = false;
                case 'On'
                    app.LogRequested = true;
            end
        end

        function updateCameraSwitchComponents(app)
            value = app.CameraSwitch.Value;
            switch value
                case 'Off'
                    app.CamLogRequested = false;
                case 'On'
                    app.CamLogRequested = true;
                    run('CameraController.m');
                    app.C = CameraController;
                    updateFreqHzDropDownComponents(app)
            end
        end
        
        function updateFreqHzDropDownComponents(app)
            value = app.FreqHzDropDown.Value;
            switch value
                case '2 Hz'
                    app.CamFreq = 2;
                case '1 Hz'
                    app.CamFreq = 1;
                case '0.5 Hz'
                    app.CamFreq = 0.5;
                case '0.2 Hz'
                    app.CamFreq = 0.2;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % This function executes when the app starts, before user interacts with UI
            
            % Set the app controls in device selection state
            setAppViewState(app, 'deviceselection');
            drawnow
            
            % Get connected devices that have the supported subsystem and measurement types
            devices = daqListSupportedDevices(app, app.DAQSubsystemTypes, app.DAQMeasurementTypes);
            
            % Store DAQ device information (filtered list) into DevicesInfo app property
            % This is used by other functions in the app
            app.DevicesInfo = devices;
           
            % Populate the device drop down list with cell array of composite device names (ID + model)
            % First element is "Select a device"
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DeviceDropDown.ItemsData = 1:numel(devices)+1;
            
            % Create a line plot and store its handle in LivePlot app property
            % This is used for updating the live plot from scansAvailable_Callback function
            app.LivePlotLine = plot(app.LiveAxes, NaN, NaN);
            app.LivePlotLine2 = plot(app.LiveAxes_2, NaN, NaN);
            app.LivePlotLine3 = plot(app.LiveAxes_3, NaN, NaN);
            app.LivePlotLine4 = plot(app.LiveAxes_4, NaN, NaN);
            
            % Turn off axes toolbar and data tips for live plot axes
            app.LiveAxes.Toolbar.Visible = 'off';
            app.LiveAxes_2.Toolbar.Visible = 'off';
            app.LiveAxes_3.Toolbar.Visible = 'off';
            app.LiveAxes_4.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.LiveAxes);
            disableDefaultInteractivity(app.LiveAxes_2);
            disableDefaultInteractivity(app.LiveAxes_3);
            disableDefaultInteractivity(app.LiveAxes_4);
            
            % Initialize the AutoscaleYSwitch, YminSpinner, and YmaxSpinner components in the correct
            % state (AutoscaleYSwitch enabled, YminSpinner and YmaxSpinner disabled).
            updateAutoscaleYSwitchComponents(app)

        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            
            if ~isempty(value)
                % Device index is offset by 1 because first element in device dropdown
                % is "Select a device" (not a device).
                deviceIndex = value-1 ;
                
                % Reset channel property options
                app.ChannelDropDown.Items = {''};
                app.ChannelDropDown_2.Items = {''};
                app.ChannelDropDown_3.Items = {''};
                app.MeasurementTypeDropDown.Items = {''};
                app.MeasurementTypeDropDown_2.Items = {''};
                app.MeasurementTypeDropDown_3.Items = {''};
                app.RangeDropDown.Items = {''};
                app.RangeDropDown_2.Items = {''};
                app.RangeDropDown_3.Items = {''};
                app.TerminalConfigDropDown.Items = {''};
                app.TerminalConfigDropDown_2.Items = {''};
                app.TerminalConfigDropDown_3.Items = {''};
                app.CouplingDropDown.Items = {''};
                app.CouplingDropDown_2.Items = {''};
                app.CouplingDropDown_3.Items = {''};
                setAppViewState(app, 'deviceselection');

                
                % Delete data acquisition object, as a new one will be created for the newly selected device
                delete(app.DAQ);
                app.DAQ = [];
                
                if deviceIndex > 0
                    % If a device is selected
                    
                    % Get subsystem information to update channel dropdown list and channel property options
                    % For devices that have an analog input or an audio input subsystem, this is the first subsystem
                    subsystem = app.DevicesInfo(deviceIndex).Subsystems(1);
                    app.ChannelDropDown.Items = cellstr(string(subsystem.ChannelNames));
                    app.ChannelDropDown_2.Items = cellstr(string(subsystem.ChannelNames(2:end)));
                    app.ChannelDropDown_3.Items = cellstr(string(subsystem.ChannelNames(3:end)));
                                        
                    % Populate available measurement types for the selected device
                    app.MeasurementTypeDropDown.Items = intersect(app.DAQMeasurementTypes,...
                                subsystem.MeasurementTypesAvailable, 'stable');
                    app.MeasurementTypeDropDown_2.Items = intersect(app.DAQMeasurementTypes,...
                                subsystem.MeasurementTypesAvailable, 'stable');
                    app.MeasurementTypeDropDown_3.Items = intersect(app.DAQMeasurementTypes,...
                                subsystem.MeasurementTypesAvailable, 'stable');

                    % Update channel and channel property options
                    updateChannelMeasurementComponents(app)

                else
                    % If no device is selected

                    % Delete existing data acquisition object
                    delete(app.DAQ);
                    app.DAQ = [];
                    
                    app.DeviceDropDown.Items{1} = 'Select a device';

                    setAppViewState(app, 'deviceselection');
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                               
            % Disable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'acquisition');               

            if app.LogRequested
                % If Log data to file switch is on
                % Create and open temporary binary file to log data to disk
                app.TempFilename = tempname;
                app.TempFile = fopen(app.TempFilename, 'w');
            end
            
            % Reset FIFO buffer data
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];
            
            try
                if app.CamLogRequested
                % If Log camera switch is on
                app.C.lag = 0.05; %adjust camera lag (sec)
                app.timerObj = timer('Period', 1/app.CamFreq, 'ExecutionMode', 'fixedRate');
                app.timerObj.TasksToExecute = Inf; % Execute indefinitely during acquisition
                set(app.timerObj, 'StartFcn', @(~,~) start(app.DAQ,'continuous')); % Start acquisition on timer start
                % set(app.timerObj, 'TimerFcn', @(~,~) app.C.Capture(num2str(0.0001+app.TimestampsFIFOBuffer(end)),0.0001+app.TimestampsFIFOBuffer(end))); % Take photos
                set(app.timerObj, 'TimerFcn', @(~,~) app.C.Capture);
                start(app.timerObj)
                else
                    start(app.DAQ,'continuous');
                end  
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Start error');   
                setAppViewState(app, 'configuration'); 
            end
            
            % Clear Log status text
            app.LogStatusText.Text = '';  

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            setAppViewState(app, 'filesave');
            stop(app.DAQ);

            if app.CamLogRequested
                stop(app.timerObj) % stop camera
            end

            if app.LogRequested
                % Log data to file switch is on
                % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)
                
                % Close temporary binary file
                fclose(app.TempFile);
                              
                % Gather metadata in preparation for saving to MAT file
                % Store relevant Daq device info
                deviceInfo = get(app.DevicesInfo(app.DeviceDropDown.Value-1));
                deviceInfo.Vendor = get(deviceInfo.Vendor);
                deviceInfo = rmfield(deviceInfo, 'Subsystems');
                metadata.DeviceInfo = deviceInfo;
                metadata.Channel = {app.ChannelDropDown.Value,app.ChannelDropDown_2.Value,app.ChannelDropDown_3.Value,'ai7'};
                metadata.MeasurementType = {app.MeasurementTypeDropDown.Value,app.MeasurementTypeDropDown_2.Value,app.MeasurementTypeDropDown_3.Value,'Voltage'};
                metadata.Range = [app.RangeDropDown.Value,app.RangeDropDown_2.Value,app.RangeDropDown_3.Value];
                metadata.Coupling = {app.CouplingDropDown.Value,app.CouplingDropDown_2.Value,app.CouplingDropDown_3.Value};
                metadata.TerminalConfig = {app.TerminalConfigDropDown.Value,app.TerminalConfigDropDown_2.Value,app.TerminalConfigDropDown_3.Value};
                metadata.Rate = app.RateEdit.Value;
                metadata.TriggerTime = datetime(app.TriggerTime, 'ConvertFrom', 'datenum', 'TimeZone', 'local');
                                
                % Open "Save As" to request destination MAT file path and file name from user
                [filename, pathname] = uiputfile({'*.mat'}, 'Save as',...
                    fullfile(app.Filepath, app.Filename));
                
                if ~(isequal(filename,0) || isequal(pathname,0))
                    % User specified a file name in a folder with write permission
                    app.Filename = filename;
                    app.Filepath = pathname;
                    cancelSaveAs = false;
                else
                    %  User clicked Cancel in "Save As" dialog
                    cancelSaveAs = true;
                end
                
                if ~cancelSaveAs
                    % Convert data from binary file to MAT file
                    matFilepath = fullfile(app.Filepath, app.Filename);
                    app.LogStatusText.Text = 'Saving data to MAT file is in progress...';
                    drawnow
                    
                    numColumns = 5;
                    binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
                    app.LogStatusText.Text = sprintf('Saving data to ''%s'' file has completed.', app.Filename);
                    
                else
                    % User clicked Cancel in "Save As" dialog
                    % Inform user that data has not been saved
                    app.LogStatusText.Text = 'Saving data to MAT file was cancelled.';
                end
            end
            
            % Enable DAQ device, channel properties, and start acquisition UI components
            setAppViewState(app, 'configuration');
        end

        % Value changed function: ChannelDropDown, ChannelDropDown_2, 
        % ...and 1 other component
        function ChannelDropDownValueChanged(app, event)
            
            updateChannelMeasurementComponents(app)
            
        end

        % Value changed function: CouplingDropDown, RangeDropDown, 
        % ...and 1 other component
        function ChannelPropertyValueChanged(app, event)
            % Shared callback for RangeDropDown, TerminalConfigDropDown, CouplingDropDown
            
            % This executes only for 'Voltage' measurement type, since for 'Audio' measurement
            % type Range never changes, and TerminalConfig and Coupling are disabled.
            
            value = event.Source.Value;
            
            % Set channel property to selected value
            % The channel property name was previously stored in the UI component Tag
            propertyName = event.Source.Tag;
            try
                set(app.DAQ.Channels(1), propertyName, value);
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Channel property error');
                event.Source.Value = event.PreviousValue;
            end
            
            % Make sure shown channel property values are not stale, as some property update can trigger changes in other properties
            % Update UI with current channel property values from data acquisition object
            currentRange = app.DAQ.Channels(1).Range;
            app.RangeDropDown.Value = [currentRange.Min currentRange.Max];
            app.TerminalConfigDropDown.Value = app.DAQ.Channels(1).TerminalConfig;
            app.CouplingDropDown.Value = app.DAQ.Channels(1).Coupling;
            
        end

        % Value changing function: RateSlider
        function RateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RateEdit.Value = changingValue;
        end

        % Value changed function: RateEdit, RateSlider
        function RateSliderValueChanged(app, event)
            % Shared callback for RateSlider and RateEdit
            
            value = event.Source.Value;
            if ~isempty(app.DAQ)
                app.DAQ.Rate = value;
                
                % Update UI with current rate and time window limits
                updateRateUIComponents(app)
                
            end
        end

        % Value changed function: YmaxEditField, YminEditField
        function YmaxminValueChanged(app, event)
            % Shared callback for YmaxEditField and YminEditField
            
            ymin = app.YminEditField.Value;
            ymax = app.YmaxEditField.Value;
            if ymax>ymin
                ylim(app.LiveAxes, [ymin, ymax]);
                ylim(app.LiveAxes_2, [ymin, ymax]);
                ylim(app.LiveAxes_3, [ymin, ymax]);
            else
                % If new limits are not correct, revert the change
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: AutoscaleYSwitch
        function AutoscaleYSwitchValueChanged(app, event)
            updateAutoscaleYSwitchComponents(app)
        end

        % Value changed function: LogdatatofileSwitch
        function LogdatatofileSwitchValueChanged(app, event)
            updateLogdatatofileSwitchComponents(app)
        end

        % Close request function: LiveDataAcquisitionUIFigure
        function LiveDataAcquisitionCloseRequest(app, event)
            
            isAcquiring = ~isempty(app.DAQ) && app.DAQ.Running;
            if isAcquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.LiveDataAcquisitionUIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
            
        end

        % Value changed function: MeasurementTypeDropDown, 
        % ...and 2 other components
        function MeasurementTypeDropDownValueChanged(app, event)
            
            updateChannelMeasurementComponents(app)

        end

        % Value changed function: CouplingDropDown_2, RangeDropDown_2, 
        % ...and 1 other component
        function RangeDropDown_2ValueChanged(app, event)
            % Shared callback for RangeDropDown, TerminalConfigDropDown, CouplingDropDown
            
            % This executes only for 'Voltage' measurement type, since for 'Audio' measurement
            % type Range never changes, and TerminalConfig and Coupling are disabled.
            
            value = event.Source.Value;
            
            % Set channel property to selected value
            % The channel property name was previously stored in the UI component Tag
            propertyName = event.Source.Tag;
            try
                set(app.DAQ.Channels(2), propertyName, value);
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Channel property error');
                event.Source.Value = event.PreviousValue;
            end
            
            % Make sure shown channel property values are not stale, as some property update can trigger changes in other properties
            % Update UI with current channel property values from data acquisition object
            currentRange = app.DAQ.Channels(2).Range;
            app.RangeDropDown_2.Value = [currentRange.Min currentRange.Max];
            app.TerminalConfigDropDown_2.Value = app.DAQ.Channels(2).TerminalConfig;
            app.CouplingDropDown_2.Value = app.DAQ.Channels(2).Coupling;
            
        end

        % Value changed function: CouplingDropDown_3, RangeDropDown_3, 
        % ...and 1 other component
        function RangeDropDown_3ValueChanged(app, event)
 % Shared callback for RangeDropDown, TerminalConfigDropDown, CouplingDropDown
            
            % This executes only for 'Voltage' measurement type, since for 'Audio' measurement
            % type Range never changes, and TerminalConfig and Coupling are disabled.
            
            value = event.Source.Value;
            
            % Set channel property to selected value
            % The channel property name was previously stored in the UI component Tag
            propertyName = event.Source.Tag;
            try
                set(app.DAQ.Channels(3), propertyName, value);
            catch exception
                % In case of error show it and revert the change
                uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Channel property error');
                event.Source.Value = event.PreviousValue;
            end
            
            % Make sure shown channel property values are not stale, as some property update can trigger changes in other properties
            % Update UI with current channel property values from data acquisition object
            currentRange = app.DAQ.Channels(3).Range;
            app.RangeDropDown_3.Value = [currentRange.Min currentRange.Max];
            app.TerminalConfigDropDown_3.Value = app.DAQ.Channels(3).TerminalConfig;
            app.CouplingDropDown_3.Value = app.DAQ.Channels(3).Coupling;
            
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            updateCameraSwitchComponents(app)
        end

        % Value changed function: FreqHzDropDown
        function FreqHzDropDownValueChanged(app, event)
            updateFreqHzDropDownComponents(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LiveDataAcquisitionUIFigure = uifigure('Visible', 'off');
            app.LiveDataAcquisitionUIFigure.Name = 'Live Data Acquisition';
            app.LiveDataAcquisitionUIFigure.CloseRequestFcn = createCallbackFcn(app, @LiveDataAcquisitionCloseRequest, true);
            app.AcquisitionPanel = uipanel(app.LiveDataAcquisitionUIFigure);
            app.StartButton = uibutton(app.AcquisitionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AcquisitionPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LogdatatofileSwitchLabel = uilabel(app.AcquisitionPanel);
            app.LogdatatofileSwitchLabel.Text = 'Log data to file';
            app.LogdatatofileSwitch = uiswitch(app.AcquisitionPanel, 'slider');
            app.LogdatatofileSwitch.ValueChangedFcn = createCallbackFcn(app, @LogdatatofileSwitchValueChanged, true);
            app.LogStatusText = uilabel(app.AcquisitionPanel);
            app.LogStatusText.Text = '';
            app.DevicePanel = uipanel(app.LiveDataAcquisitionUIFigure);
            app.ChannelDropDownLabel = uilabel(app.DevicePanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.DevicePanel);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.MeasurementTypeDropDownLabel = uilabel(app.DevicePanel);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.DevicePanel);
            app.MeasurementTypeDropDown.Items = {};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown.Value = {};
            app.RangeDropDownLabel = uilabel(app.DevicePanel);
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.DevicePanel);
            app.RangeDropDown.Items = {};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.RangeDropDown.Value = {};
            app.TerminalConfigDropDownLabel = uilabel(app.DevicePanel);
            app.TerminalConfigDropDownLabel.Text = 'Terminal Config.';
            app.TerminalConfigDropDown = uidropdown(app.DevicePanel);
            app.TerminalConfigDropDown.Items = {};
            app.TerminalConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.TerminalConfigDropDown.Value = {};
            app.CouplingDropDownLabel = uilabel(app.DevicePanel);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingDropDown = uidropdown(app.DevicePanel);
            app.CouplingDropDown.Items = {};
            app.CouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelPropertyValueChanged, true);
            app.CouplingDropDown.Value = {};
            app.PPT1Label = uilabel(app.DevicePanel);
            app.PPT1Label.Text = 'PPT1';
            app.LiveDataAcquisitionPermeameterLabel = uilabel(app.LiveDataAcquisitionUIFigure);
            app.LiveDataAcquisitionPermeameterLabel.Text = 'Live Data Acquisition Permeameter';
            app.LiveViewPanel = uipanel(app.LiveDataAcquisitionUIFigure);
            app.LiveAxes = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes, 'PPT1')
            xlabel(app.LiveAxes, 'Time (s)')
            ylabel(app.LiveAxes, 'Voltage (V)')
            app.LiveAxes_3 = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes_3, 'PPT3')
            xlabel(app.LiveAxes_3, 'Time (s)')
            ylabel(app.LiveAxes_3, 'Voltage (V)')
            app.LiveAxes_2 = uiaxes(app.LiveViewPanel);
            title(app.LiveAxes_2, 'PPT2')
            xlabel(app.LiveAxes_2, 'Time (s)')
            ylabel(app.LiveAxes_2, 'Voltage (V)')
            app.AutoscaleYSwitchLabel = uilabel(app.LiveViewPanel);
            app.AutoscaleYSwitchLabel.Text = 'Autoscale Y';
            app.AutoscaleYSwitch = uiswitch(app.LiveViewPanel, 'slider');
            app.AutoscaleYSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleYSwitchValueChanged, true);
            app.YminEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YminEditField.Value = -1;
            app.YmaxEditFieldLabel = uilabel(app.LiveViewPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxminValueChanged, true);
            app.YmaxEditField.Value = 1;
            app.TimewindowsEditFieldLabel = uilabel(app.LiveViewPanel);
            app.TimewindowsEditFieldLabel.Text = 'Time window (s)';
            app.TimewindowEditField = uieditfield(app.LiveViewPanel, 'numeric');
            app.TimewindowEditField.Value = 1;
            app.DevicePanel_2 = uipanel(app.LiveDataAcquisitionUIFigure);
            app.ChannelDropDown_2Label = uilabel(app.DevicePanel_2);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.DevicePanel_2);
            app.ChannelDropDown_2.Items = {};
            app.ChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown_2.Value = {};
            app.RangeDropDown_2Label = uilabel(app.DevicePanel_2);
            app.RangeDropDown_2Label.Text = 'Range';
            app.RangeDropDown_2 = uidropdown(app.DevicePanel_2);
            app.RangeDropDown_2.Items = {};
            app.RangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_2ValueChanged, true);
            app.RangeDropDown_2.Value = {};
            app.TerminalConfigDropDown_2Label = uilabel(app.DevicePanel_2);
            app.TerminalConfigDropDown_2Label.Text = 'Terminal Config.';
            app.TerminalConfigDropDown_2 = uidropdown(app.DevicePanel_2);
            app.TerminalConfigDropDown_2.Items = {};
            app.TerminalConfigDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_2ValueChanged, true);
            app.TerminalConfigDropDown_2.Value = {};
            app.CouplingDropDown_2Label = uilabel(app.DevicePanel_2);
            app.CouplingDropDown_2Label.Text = 'Coupling';
            app.CouplingDropDown_2 = uidropdown(app.DevicePanel_2);
            app.CouplingDropDown_2.Items = {};
            app.CouplingDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_2ValueChanged, true);
            app.CouplingDropDown_2.Value = {};
            app.PPT2Label = uilabel(app.DevicePanel_2);
            app.PPT2Label.Text = 'PPT2';
            app.MeasurementTypeDropDown_2Label = uilabel(app.DevicePanel_2);
            app.MeasurementTypeDropDown_2Label.Text = 'Measurement Type';
            app.MeasurementTypeDropDown_2 = uidropdown(app.DevicePanel_2);
            app.MeasurementTypeDropDown_2.Items = {};
            app.MeasurementTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown_2.Value = {};
            app.DevicePanel_3 = uipanel(app.LiveDataAcquisitionUIFigure);
            app.ChannelDropDown_3Label = uilabel(app.DevicePanel_3);
            app.ChannelDropDown_3Label.Text = 'Channel';
            app.ChannelDropDown_3 = uidropdown(app.DevicePanel_3);
            app.ChannelDropDown_3.Items = {};
            app.ChannelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown_3.Value = {};
            app.MeasurementTypeDropDown_3Label = uilabel(app.DevicePanel_3);
            app.MeasurementTypeDropDown_3Label.Text = 'Measurement Type';
            app.MeasurementTypeDropDown_3 = uidropdown(app.DevicePanel_3);
            app.MeasurementTypeDropDown_3.Items = {};
            app.MeasurementTypeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown_3.Value = {};
            app.RangeDropDown_3Label = uilabel(app.DevicePanel_3);
            app.RangeDropDown_3Label.Text = 'Range';
            app.RangeDropDown_3 = uidropdown(app.DevicePanel_3);
            app.RangeDropDown_3.Items = {};
            app.RangeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_3ValueChanged, true);
            app.RangeDropDown_3.Value = {};
            app.TerminalConfigDropDown_3Label = uilabel(app.DevicePanel_3);
            app.TerminalConfigDropDown_3Label.Text = 'Terminal Config.';
            app.TerminalConfigDropDown_3 = uidropdown(app.DevicePanel_3);
            app.TerminalConfigDropDown_3.Items = {};
            app.TerminalConfigDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_3ValueChanged, true);
            app.TerminalConfigDropDown_3.Value = {};
            app.CouplingDropDown_3Label = uilabel(app.DevicePanel_3);
            app.CouplingDropDown_3Label.Text = 'Coupling';
            app.CouplingDropDown_3 = uidropdown(app.DevicePanel_3);
            app.CouplingDropDown_3.Items = {};
            app.CouplingDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RangeDropDown_3ValueChanged, true);
            app.CouplingDropDown_3.Value = {};
            app.PPT3Label = uilabel(app.DevicePanel_3);
            app.PPT3Label.Text = 'PPT3';
            app.DevicePanel_4 = uipanel(app.LiveDataAcquisitionUIFigure);
            app.DeviceDropDownLabel = uilabel(app.DevicePanel_4);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DevicePanel_4);
            app.DeviceDropDown.Items = {'Detecting devices...'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Detecting devices...';
            app.RateEdit = uieditfield(app.DevicePanel_4, 'numeric');
            app.RateEdit.Limits = [1e-06 10000000];
            app.RateEdit.ValueDisplayFormat = '%.1f';
            app.RateEdit.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateEdit.Value = 1000;
            app.RatescanssSliderLabel = uilabel(app.DevicePanel_4);
            app.RatescanssSliderLabel.Text = 'Rate (scans/s)';
            app.RateSlider = uislider(app.DevicePanel_4);
            app.RateSlider.Limits = [1e-06 1000];
            app.RateSlider.ValueChangedFcn = createCallbackFcn(app, @RateSliderValueChanged, true);
            app.RateSlider.ValueChangingFcn = createCallbackFcn(app, @RateSliderValueChanging, true);
            app.RateSlider.Value = 1000;
            app.Panel = uipanel(app.LiveDataAcquisitionUIFigure);
            app.LiveAxes_4 = uiaxes(app.Panel);
            title(app.LiveAxes_4, 'Flowmeter')
            xlabel(app.LiveAxes_4, 'Time (s)')
            ylabel(app.LiveAxes_4, 'Counts (-)')
            app.Panel_2 = uipanel(app.LiveDataAcquisitionUIFigure);
            app.CameraSwitchLabel = uilabel(app.Panel_2);
            app.CameraSwitchLabel.Text = 'Camera';
            app.CameraSwitch = uiswitch(app.Panel_2, 'slider');
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.FreqHzDropDownLabel = uilabel(app.Panel_2);
            app.FreqHzDropDownLabel.Text = 'Freq (Hz)';
            app.FreqHzDropDown = uidropdown(app.Panel_2);
            app.FreqHzDropDown.Items = {'2 Hz', '1 Hz', '0.5 Hz', '0.2 Hz'};
            app.FreqHzDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqHzDropDownValueChanged, true);
            app.FreqHzDropDown.Value = '1 Hz';
            % Show the figure after all components are created
            app.LiveDataAcquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveDataPPTsAndFlowAndCamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LiveDataAcquisitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LiveDataAcquisitionUIFigure)
        end
    end
end